classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        plotButton          matlab.ui.control.Button
        amplitudeKnobLabel  matlab.ui.control.Label
        amplitudeKnob       matlab.ui.control.Knob
        frequencyKnobLabel  matlab.ui.control.Label
        frequencyKnob       matlab.ui.control.Knob
        DropDownLabel       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            ax = app.UIAxes;
            t=0:0.01:5*pi;
            if isequal(app.DropDown.Value,'sine')
            y=app.amplitudeKnob.Value*sin(2*pi*app.frequencyKnob.Value.*t);
            else 
            y=app.amplitudeKnob.Value*square(2*pi*app.frequencyKnob.Value.*t);
            end
            plot(ax,t,y);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.amplitudeKnobLabel = uilabel(app.UIFigure);
            app.amplitudeKnobLabel.Text = 'amplitude';
            app.amplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.amplitudeKnob.Limits = [0 10];
            app.frequencyKnobLabel = uilabel(app.UIFigure);
            app.frequencyKnobLabel.Text = 'frequency';
            app.frequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.frequencyKnob.Limits = [0 5];
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sine', 'square'};
            app.DropDown.Value = 'sine';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end